classdef power_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConsuptionCalculatorUIFigure  matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        StartSlider                   matlab.ui.control.Slider
        StopSlider                    matlab.ui.control.Slider
        TechnologyDropDownLabel       matlab.ui.control.Label
        TechnologySelect              matlab.ui.control.DropDown
        SeconLabel                    matlab.ui.control.Label
        SecondSelect                  matlab.ui.control.DropDown
        ExtraLabel                    matlab.ui.control.Label
        ExtraSelect                   matlab.ui.control.DropDown
        MsgSizeLabel                  matlab.ui.control.Label
        MsgSizeSelect                 matlab.ui.control.DropDown
        LoadButton                    matlab.ui.control.Button
        StartTimeLabel                matlab.ui.control.Label
        StopTimeLabel                 matlab.ui.control.Label
        ConsumptionLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        startLine
        stopLine
        samplePeriod = 2.048e-05;
        xEnd = 100;
        xVals = 0;
        yVals = 0;
        startLast = 0;
        stopLast = 10;
        loaded = false;
    end
    
    methods (Access = private)
        
        function name = getFileName(app)
            technology = app.TechnologySelect.Value;
            second = app.SecondSelect.Value;
            msgSize = app.MsgSizeSelect.Value;
            extra = app.ExtraSelect.Value;
            
                if (strcmp('Attach', second) || strcmp('Idle', second) || strcmp('TAU', second))
                    msgSize = '0';
                end
                
                if (strcmp('NB-IoT', technology))
                    name = sprintf('./%s/%s_ECL%s_%s.mat', technology, second, extra, msgSize);
                end
                
                if (strcmp('LoRaWAN', technology))
                    name = sprintf('./%s/%s_%s_%s.mat', technology, second, extra, msgSize);
                end
                
                if(strcmp('Sigfox', technology))
                   name = sprintf('./%s/%s_%s.mat', technology, second, msgSize);
                end
                name = strrep(name, ' ', '_');
        end
        
        function step = getStep(~, range)
            if range <= 1
                step = 0.05;
            elseif range <= 4
                step = 0.1;
            elseif range <= 10
                step = 0.25;
            elseif range <= 25
                step = 0.5;
            else
                step = 1;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.startLine = xline(app.UIAxes, 0, 'Color', 'red', 'LineWidth', 1.2);
            app.stopLine = xline(app.UIAxes, 10, 'Color', 'red', 'LineWidth', 1.2);
        end

        % Value changed function: TechnologySelect
        function TechnologySelectValueChanged(app, event)
            value = app.TechnologySelect.Value;
            app.MsgSizeLabel.Enable = true;
            app.MsgSizeSelect.Enable = true;
            switch value
                case 'NB-IoT' 
                    app.SeconLabel.Text = 'Mode';
                    app.SecondSelect.Items = {'Attach', 'Idle', 'Tx', 'TAU', 'TAU+Tx'};
                    app.MsgSizeSelect.Items = {'2', '12', '50', '100', '200', '222', '384', '500'};
                    app.ExtraLabel.Text = 'ECL';
                    app.ExtraSelect.Items = {'0', '1', '2'};
                    app.ExtraSelect.Visible = true;
                    app.ExtraLabel.Visible = true;
                    if (strcmp('Attach', app.SecondSelect.Value) || strcmp('Idle', app.SecondSelect.Value) || strcmp('TAU', app.SecondSelect.Value))
                        app.MsgSizeLabel.Enable = false;
                        app.MsgSizeSelect.Enable = false;
                    end
                case 'Sigfox'
                    app.SeconLabel.Text = 'Module';
                    app.SecondSelect.Items = {'Telit', 'S2-LP'};
                    app.MsgSizeSelect.Items = {'2', '12'};
                    app.ExtraSelect.Visible = false;
                    app.ExtraLabel.Visible = false;
                case 'LoRaWAN'
                    app.SeconLabel.Text = 'Spreading Factor';
                    app.SecondSelect.Items = {'SF7', 'SF8', 'SF9', 'SF10', 'SF11', 'SF12'};
                    app.MsgSizeSelect.Items = {'2', '12', '50', '100', '200', '222'};
                    app.ExtraSelect.Visible = true;
                    app.ExtraLabel.Visible = true;
                    app.ExtraSelect.Items = {'ACK', 'NO ACK'};
                    app.ExtraLabel.Text = 'Mode';
            end
        end

        % Value changed function: SecondSelect
        function SecondSelectValueChanged(app, event)
            value = app.SecondSelect.Value;
            app.MsgSizeSelect.Enable = true;
            app.MsgSizeLabel.Enable = true;
            switch value
                case 'Attach'
                    app.MsgSizeSelect.Enable = false;
                    app.MsgSizeLabel.Enable = false;
                case 'Idle'
                    app.MsgSizeSelect.Enable = false;
                    app.MsgSizeLabel.Enable = false;
                case 'TAU'
                    app.MsgSizeSelect.Enable = false;
                    app.MsgSizeLabel.Enable = false;
                case 'SF7'
                    app.MsgSizeSelect.Items = {'2', '12', '50', '100', '200', '222'};
                case 'SF8'
                    app.MsgSizeSelect.Items = {'2', '12', '50', '100', '200', '222'};
                case 'SF9'
                    app.MsgSizeSelect.Items = {'2', '12', '50', '100'};
                case 'SF10'
                    app.MsgSizeSelect.Items = {'2', '12', '50'};
                case 'SF11'
                    app.MsgSizeSelect.Items = {'2', '12', '50'};
                case 'SF12'
                    app.MsgSizeSelect.Items = {'2', '12', '50'};
            end
        end

        % Value changed function: StartSlider
        function StartSliderValueChanged(app, event)
            if (event.Value >= app.stopLast)
               app.StartSlider.Value = app.startLast;
               return;
            end
            app.startLine.Value = event.Value;
            app.StartTimeLabel.Text = sprintf('Start Time = %.2fs', event.Value);
            app.startLast = app.StartSlider.Value;
            if (app.loaded == true)
                [joul, wh] = get_consumption(app.xVals, app.yVals, app.StartSlider.Value, app.StopSlider.Value, app.samplePeriod);
                app.ConsumptionLabel.Text = sprintf('Consumption: %fJ (%d Wh)', joul, wh);
            end
        end

        % Value changed function: StopSlider
        function StopSliderValueChanged(app, event)
            if (event.Value <= app.startLast)
               app.StopSlider.Value = app.stopLast;
               return;
            end
            app.stopLine.Value = event.Value;
            app.StopTimeLabel.Text = sprintf('Stop Time = %.2fs', event.Value);
            app.stopLast = app.StopSlider.Value;
            if (app.loaded == true)
                [joul, wh] = get_consumption(app.xVals, app.yVals, app.StartSlider.Value, app.StopSlider.Value, app.samplePeriod);
                app.ConsumptionLabel.Text = sprintf('Consumption: %fJ (%d Wh)', joul, wh);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [x, y] = load_data(app.getFileName(), app.samplePeriod);
            app.xVals = x;
            app.yVals = y;
            app.UIAxes.YLim = [0 ceil(max(y)) + 25];
            plot(app.UIAxes, x, y);
            app.xEnd = ceil(x(end));
            app.UIAxes.XLim = [0, app.xEnd];
            app.StartSlider.Limits = [0, app.xEnd];
            app.StopSlider.Limits = [0, app.xEnd];
            app.StopSlider.Value = x(end);
            app.StartSlider.Value = 0;
            app.stopLast = x(end);
            app.startLast = 0;
            step = app.getStep(app.xEnd);
            app.StartSlider.MajorTicks = 0:step:app.xEnd;
            app.StopSlider.MajorTicks = 0:step:app.xEnd;
            app.StartSlider.MinorTicks = [];
            app.StopSlider.MinorTicks = []; 
            app.StartTimeLabel.Text = sprintf('Start Time = %.2fs', 0);
            app.StopTimeLabel.Text = sprintf('Stop Time = %.2fs', x(end));
            app.startLine = xline(app.UIAxes, 0, 'Color', 'red', 'LineWidth', 1.2);
            app.stopLine = xline(app.UIAxes, x(end), 'Color', 'red', 'LineWidth', 1.2);
            [joul, wh] = get_consumption(app.xVals, app.yVals, app.StartSlider.Value, app.StopSlider.Value, app.samplePeriod);
            app.ConsumptionLabel.Text = sprintf('Consumption: %fJ (%d Wh)', joul, wh);
            app.loaded = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConsuptionCalculatorUIFigure = uifigure('Visible', 'off');
            app.ConsuptionCalculatorUIFigure.Name = 'Consuption Calculator';
            app.UIAxes = uiaxes(app.ConsuptionCalculatorUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Crrent [mA]')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 300];
            app.StartSlider = uislider(app.ConsuptionCalculatorUIFigure);
            app.StartSlider.Limits = [0 10];
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @StartSliderValueChanged, true);
            app.StartSlider.MinorTicks = [];
            app.StopSlider = uislider(app.ConsuptionCalculatorUIFigure);
            app.StopSlider.Limits = [0 10];
            app.StopSlider.ValueChangedFcn = createCallbackFcn(app, @StopSliderValueChanged, true);
            app.StopSlider.MinorTicks = [];
            app.StopSlider.Value = 10;
            app.TechnologyDropDownLabel = uilabel(app.ConsuptionCalculatorUIFigure);
            app.TechnologyDropDownLabel.Text = 'Technology';
            app.TechnologySelect = uidropdown(app.ConsuptionCalculatorUIFigure);
            app.TechnologySelect.Items = {'NB-IoT', 'Sigfox', 'LoRaWAN'};
            app.TechnologySelect.ValueChangedFcn = createCallbackFcn(app, @TechnologySelectValueChanged, true);
            app.TechnologySelect.Value = 'NB-IoT';
            app.SeconLabel = uilabel(app.ConsuptionCalculatorUIFigure);
            app.SeconLabel.Text = 'Mode';
            app.SecondSelect = uidropdown(app.ConsuptionCalculatorUIFigure);
            app.SecondSelect.Items = {'Attach', 'Idle', 'Tx', 'TAU', 'TAU+Tx'};
            app.SecondSelect.ValueChangedFcn = createCallbackFcn(app, @SecondSelectValueChanged, true);
            app.SecondSelect.Value = 'Attach';
            app.ExtraLabel = uilabel(app.ConsuptionCalculatorUIFigure);
            app.ExtraLabel.Text = 'ECL';
            app.ExtraSelect = uidropdown(app.ConsuptionCalculatorUIFigure);
            app.ExtraSelect.Items = {'0', '1', '2'};
            app.ExtraSelect.Value = '0';
            app.MsgSizeLabel = uilabel(app.ConsuptionCalculatorUIFigure);
            app.MsgSizeLabel.Enable = 'off';
            app.MsgSizeLabel.Text = 'Msg. Size';
            app.MsgSizeSelect = uidropdown(app.ConsuptionCalculatorUIFigure);
            app.MsgSizeSelect.Items = {'2', '12', '50', '100', '200', '222', '384', '500'};
            app.MsgSizeSelect.Enable = 'off';
            app.MsgSizeSelect.Value = '2';
            app.LoadButton = uibutton(app.ConsuptionCalculatorUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.StartTimeLabel = uilabel(app.ConsuptionCalculatorUIFigure);
            app.StartTimeLabel.Text = 'Start Time = 0s';
            app.StopTimeLabel = uilabel(app.ConsuptionCalculatorUIFigure);
            app.StopTimeLabel.Text = 'Stop Time = 10s';
            app.ConsumptionLabel = uilabel(app.ConsuptionCalculatorUIFigure);
            app.ConsumptionLabel.Text = '';
            % Show the figure after all components are created
            app.ConsuptionCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = power_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConsuptionCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConsuptionCalculatorUIFigure)
        end
    end
end